classdef RadarSystemApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BtnSimulateButton        matlab.ui.control.Button
        LabelMaxRange            matlab.ui.control.Label
        LabelSNR                 matlab.ui.control.Label
        EditFreqEditField        matlab.ui.control.NumericEditField
        EditFreqEditFieldLabel   matlab.ui.control.Label
        EditPowerEditField       matlab.ui.control.NumericEditField
        EditPowerEditFieldLabel  matlab.ui.control.Label
        EditRangeEditField       matlab.ui.control.NumericEditField
        EditRangeEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        RadarEngine % Instance of the MasterRadarSystem
        SimTimer    % Timer for Phase 2 dynamic pointer tracking
        hTarget     % Plot handle for fast memory-efficient updates
        CurrentTime % Tracking simulation time
    end
    
    methods (Access = private)
        
        function updateCalculations(app)
            % 1. Ask the math engine to compute the physics
            [snr, r_max, ~, ~] = app.RadarEngine.calcRadarFundamentals();
        
            % 2. Update the Dashboard UI
            app.LabelSNR.Text = sprintf('%.2f dB', snr);
            app.LabelMaxRange.Text = sprintf('%.2f km', r_max / 1000);
        
            if snr < 13
                app.LabelSNR.FontColor = [0.8 0 0]; % Red if below detection threshold
            else
                app.LabelSNR.FontColor = [0 0.6 0]; % Green if detected
            end
        
            % 3. Update the 3D Static Plot
            R = app.RadarEngine.TargetState.Range;
            Az = app.RadarEngine.TargetState.Az;
            El = app.RadarEngine.TargetState.El;
        
            x = R * cosd(El) * cosd(Az);
            y = R * cosd(El) * sind(Az);
            z = R * sind(El);
        
            if isempty(app.hTarget) || ~isvalid(app.hTarget)
                % Initial plot setup
                app.hTarget = scatter3(app.UIAxes, x, y, z, 100, 'g', 'filled');
                title(app.UIAxes, 'Live Tracking Volume');
                xlabel(app.UIAxes, 'X (m)'); ylabel(app.UIAxes, 'Y (m)'); zlabel(app.UIAxes, 'Z (m)');
                grid(app.UIAxes, 'on'); hold(app.UIAxes, 'on'); view(app.UIAxes, 3);
                app.UIAxes.XLim = [-60000 60000];
                app.UIAxes.YLim = [-60000 60000];
                app.UIAxes.ZLim = [0 60000];
            else
                % Zero-leak handle updating for high-speed simulation
                set(app.hTarget, 'XData', x, 'YData', y, 'ZData', z);
            end
        end
        
        function stepSimulation(app)
            % Phase 2 Hook: Update target kinematics
            dt = app.SimTimer.Period;
            app.CurrentTime = app.CurrentTime + dt;
        
            vel = app.RadarEngine.TargetState.Vel;
            app.RadarEngine.TargetState.Range = app.RadarEngine.TargetState.Range + (vel * dt);
        
            % Keep UI inputs synced with dynamic model changes using the correct App Designer name
            app.EditRangeEditField.Value = app.RadarEngine.TargetState.Range / 1000;
        
            app.updateCalculations();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RadarEngine = MasterRadarSystem();
            
            % Update all three to use 'EditField' suffix
            app.EditRangeEditField.Value = app.RadarEngine.TargetState.Range / 1000;
            app.EditPowerEditField.Value = app.RadarEngine.HardwareSpecs.Pt / 1000;
            app.EditFreqEditField.Value = app.RadarEngine.HardwareSpecs.Freq / 1e9;
        
            % Fixed Timer Precision (0.033 seconds = ~30 Hz)
            app.SimTimer = timer('ExecutionMode', 'fixedRate', ...
                                 'Period', 0.033, ... 
                                 'TimerFcn', @(~,~) app.stepSimulation());
            app.CurrentTime = 0;
        
            app.updateCalculations();   
        end

        % Value changed function: EditFreqEditField
        function EditFreqEditFieldValueChanged(app, event)
            app.RadarEngine.TargetState.Range = app.EditRangeEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Pt = app.EditPowerEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Freq = app.EditFreqEditField.Value * 1e9;
            
            app.updateCalculations();
        end

        % Value changed function: EditPowerEditField
        function EditPowerEditFieldValueChanged(app, event)
            app.RadarEngine.TargetState.Range = app.EditRangeEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Pt = app.EditPowerEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Freq = app.EditFreqEditField.Value * 1e9;
            
            app.updateCalculations();
        end

        % Value changed function: EditRangeEditField
        function EditRangeEditFieldValueChanged(app, event)
            app.RadarEngine.TargetState.Range = app.EditRangeEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Pt = app.EditPowerEditField.Value * 1000;
            app.RadarEngine.HardwareSpecs.Freq = app.EditFreqEditField.Value * 1e9;
            
            app.updateCalculations();
        end

        % Button pushed function: BtnSimulateButton
        function BtnSimulateButtonPushed(app, event)
            if strcmp(app.SimTimer.Running, 'off')
                app.BtnSimulateButton.Text = 'Stop Dynamics';
                app.BtnSimulateButton.BackgroundColor = [0.8 0.2 0.2];
                start(app.SimTimer);
            else
                app.BtnSimulateButton.Text = 'Run Pointer Dynamics';
                app.BtnSimulateButton.BackgroundColor = [0.2 0.6 0.2];
                stop(app.SimTimer);
            end    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.SimTimer)
                stop(app.SimTimer);
                delete(app.SimTimer);
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes3D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EditRangeEditFieldLabel = uilabel(app.UIFigure);
            app.EditRangeEditFieldLabel.Text = 'EditRange';
            app.EditRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditRangeEditField.ValueChangedFcn = createCallbackFcn(app, @EditRangeEditFieldValueChanged, true);
            app.EditPowerEditFieldLabel = uilabel(app.UIFigure);
            app.EditPowerEditFieldLabel.Text = 'EditPower';
            app.EditPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditPowerEditField.ValueChangedFcn = createCallbackFcn(app, @EditPowerEditFieldValueChanged, true);
            app.EditFreqEditFieldLabel = uilabel(app.UIFigure);
            app.EditFreqEditFieldLabel.Text = 'EditFreq';
            app.EditFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFreqEditField.ValueChangedFcn = createCallbackFcn(app, @EditFreqEditFieldValueChanged, true);
            app.LabelSNR = uilabel(app.UIFigure);
            app.LabelSNR.Text = 'LabelSNR';
            app.LabelMaxRange = uilabel(app.UIFigure);
            app.LabelMaxRange.Text = 'LabelMaxRange';
            app.BtnSimulateButton = uibutton(app.UIFigure, 'push');
            app.BtnSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulateButton.Text = 'BtnSimulate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarSystemApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end